classdef solid_inclusion_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalculatorTab                   matlab.ui.container.Tab
        TItleLabel                      matlab.ui.control.Label
        SetCompBackground               matlab.ui.control.Button
        SetPTBackground                 matlab.ui.control.Button
        IsobarBackground                matlab.ui.control.Button
        SetCompDark                     matlab.ui.control.Button
        SetPTDark                       matlab.ui.control.Button
        TextSetComp                     matlab.ui.control.Label
        TextSetPT                       matlab.ui.control.Label
        IsobarDark                      matlab.ui.control.Button
        IsomekeGraphButton              matlab.ui.control.Button
        AnyTex2                         matlab.ui.control.Label
        AnyTex2_2                       matlab.ui.control.Label
        InclusionHeadint                matlab.ui.control.Label
        HostHeading                     matlab.ui.control.Label
        Component2DropDown              matlab.ui.control.DropDown
        Component3DropDown              matlab.ui.control.DropDown
        Component3mol                   matlab.ui.control.NumericEditField
        Component1DropDown              matlab.ui.control.DropDown
        Component1mol                   matlab.ui.control.NumericEditField
        Component2mol                   matlab.ui.control.NumericEditField
        HostDropDown                    matlab.ui.control.DropDown
        HostDropDown_2                  matlab.ui.control.DropDown
        HostDropDown_3                  matlab.ui.control.DropDown
        HostMol3                        matlab.ui.control.NumericEditField
        HostMol2                        matlab.ui.control.NumericEditField
        HostMol1                        matlab.ui.control.NumericEditField
        HostDropDown_4                  matlab.ui.control.DropDown
        HostMol4                        matlab.ui.control.NumericEditField
        HostDropDown_5                  matlab.ui.control.DropDown
        HostMol5                        matlab.ui.control.NumericEditField
        Component4DropDown              matlab.ui.control.DropDown
        Component4mol                   matlab.ui.control.NumericEditField
        Component5DropDown              matlab.ui.control.DropDown
        Component5mol                   matlab.ui.control.NumericEditField
        TemperatureEditField            matlab.ui.control.NumericEditField
        PentrapEditField                matlab.ui.control.NumericEditField
        AnyText                         matlab.ui.control.Label
        AnyTex2_3                       matlab.ui.control.Label
        AnyTex2_4                       matlab.ui.control.Label
        PinclusionEditField             matlab.ui.control.NumericEditField
        AnyText_2                       matlab.ui.control.Label
        anytext                         matlab.ui.control.Label
        EntrapmentPressureButton        matlab.ui.control.Button
        AnyTex2_5                       matlab.ui.control.Label
        TentrapEditField                matlab.ui.control.NumericEditField
        AnyText_3                       matlab.ui.control.Label
        EntrapmentTempButton            matlab.ui.control.Button
        ActiveCalculationStepEditFieldLabel  matlab.ui.control.Label
        ActiveCalculationStepEditFieldLabel_2  matlab.ui.control.Label
        ActiveCalculationStepEditField  matlab.ui.control.NumericEditField
        TupdateField                    matlab.ui.control.NumericEditField
        ActiveCalculationStepEditFieldLabel_3  matlab.ui.control.Label
        AnyTex2_7                       matlab.ui.control.Label
        SetPressureAxesLabel            matlab.ui.control.Label
        SetTemperatureAxesLabel         matlab.ui.control.Label
        toLabel                         matlab.ui.control.Label
        ActiveCalculationStepEditFieldLabel_4  matlab.ui.control.Label
        HighPressureEditField           matlab.ui.control.NumericEditField
        LowPressureEditField            matlab.ui.control.NumericEditField
        toLabel_2                       matlab.ui.control.Label
        HighTemperatureEditField        matlab.ui.control.NumericEditField
        LowTemperatureEditField         matlab.ui.control.NumericEditField
        AnyTex2_8                       matlab.ui.control.Label
        RangeofInclPressureLabel        matlab.ui.control.Label
        ActiveCalculationStepEditFieldLabel_5  matlab.ui.control.Label
        PinclHigh                       matlab.ui.control.NumericEditField
        PinclLow                        matlab.ui.control.NumericEditField
        toLabel_3                       matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PinclupdateField                matlab.ui.control.NumericEditField
        ActiveCalculationStepEditFieldLabel_6  matlab.ui.control.Label
        PressureEditField               matlab.ui.control.NumericEditField
        AnyTex2_6                       matlab.ui.control.Label
        anytext_2                       matlab.ui.control.Label
        Pincl_int                       matlab.ui.control.NumericEditField
        PressureEditField_int           matlab.ui.control.NumericEditField
        TemperatureEditField_int        matlab.ui.control.NumericEditField
        IntervalsLabel                  matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MolFractionLabel                matlab.ui.control.Label
        ActiveCalculationStepEditFieldLabel_7  matlab.ui.control.Label
        ActiveCalculationStepEditFieldLabel_8  matlab.ui.control.Label
        AnyTex2_20                      matlab.ui.control.Label
        OutputDataTab                   matlab.ui.container.Tab
        LoadDataButton                  matlab.ui.control.Button
        TItleLabel_2                    matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        AnyTex2_9                       matlab.ui.control.Label
        InstructionsandInformationTab   matlab.ui.container.Tab
        TItleLabel_3                    matlab.ui.control.Label
        SetCompBackground_2             matlab.ui.control.Button
        SetCompDark_2                   matlab.ui.control.Button
        TextSetComp_2                   matlab.ui.control.Label
        SetCompBackground_3             matlab.ui.control.Button
        SetCompDark_3                   matlab.ui.control.Button
        TextSetComp_3                   matlab.ui.control.Label
        AnyTex2_10                      matlab.ui.control.Label
        AnyTex2_11                      matlab.ui.control.Label
        AnyTex2_12                      matlab.ui.control.Label
        AnyTex2_13                      matlab.ui.control.Label
        AnyTex2_14                      matlab.ui.control.Label
        AnyTex2_15                      matlab.ui.control.Label
        AnyTex2_16                      matlab.ui.control.Label
        AnyTex2_17                      matlab.ui.control.Label
        AnyTex2_18                      matlab.ui.control.Label
        AnyTex2_19                      matlab.ui.control.Label
    end

    properties (Access = private)
        hp_dataset =  readtable('thermodynamic_properties.xlsx','ReadRowNames',true); % Description
    end


    methods (Access = private)

        % Button pushed function: IsomekeGraphButton
        function IsobarGraph(app, event)
    clc       
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Code here performs all the calculations to produce an Isomeke Graph
            
        % Step 1: Read Excel Spreadsheet for all Thermodynamic Properties
        hp_dataset = readtable('thermodynamic_properties.xlsx','ReadRowNames',true);
    
        % Step 2: Load user Inputs of Host and Inclusion Composition. Can range from 1 to 5 components to best represent mineral.   
        inclusion_abv{1} = app.Component1DropDown.Value;
        in_mol1 = app.Component1mol.Value;
        inclusion_mole_fraction{1} = in_mol1;
    
        inclusion_abv{2} = app.Component2DropDown.Value;
        in_mol2 = app.Component2mol.Value;
        inclusion_mole_fraction{2} = in_mol2;
            
        inclusion_abv{3} = app.Component3DropDown.Value;
        in_mol3 = app.Component3mol.Value;
        inclusion_mole_fraction{3} = in_mol3;
    
        inclusion_abv{4} = app.Component4DropDown.Value;
        in_mol4 = app.Component4mol.Value;
        inclusion_mole_fraction{4} = in_mol4;
            
        inclusion_abv{5} = app.Component5DropDown.Value;
        in_mol5 = app.Component5mol.Value;
        inclusion_mole_fraction{5} = in_mol5;
          
        host_abv{1} = app.HostDropDown.Value;
        h_mol1 = app.HostMol1.Value;
        host_mole_fraction{1} = h_mol1;
                    
        host_abv{2} = app.HostDropDown_2.Value;
        h_mol2 = app.HostMol2.Value;
        host_mole_fraction{2} = h_mol2;
                    
        host_abv{3} = app.HostDropDown_3.Value;
        h_mol3 = app.HostMol3.Value;
        host_mole_fraction{3} = h_mol3;
            
        host_abv{4} = app.HostDropDown_4.Value;
        h_mol4 = app.HostMol4.Value;
        host_mole_fraction{4} = h_mol4;
            
        host_abv{5} = app.HostDropDown_5.Value;
        h_mol5 = app.HostMol5.Value;
        host_mole_fraction{5} = h_mol5;
            
        % Get rid of hosts and inclusions for which no component mole fraction has been entered
        count_incl = 1;
        count_host = 1;
            
            for ii = 1:5
                if host_mole_fraction{ii} ~= 0
                    keep_host_abv{count_host} = host_abv{ii};
                    keep_host_mole_fraction{count_host} = host_mole_fraction{ii};
                    count_host = count_host + 1;
                end
                if inclusion_mole_fraction{ii} ~= 0
                    keep_inclusion_abv{count_incl} = inclusion_abv{ii};
                    keep_inclusion_mole_fraction{count_incl} = inclusion_mole_fraction{ii};
                    count_incl = count_incl + 1;
                end
            end
            
        host_abv = keep_host_abv;
        host_mole_fraction = keep_host_mole_fraction;
        inclusion_abv = keep_inclusion_abv;
        inclusion_mole_fraction = keep_inclusion_mole_fraction;
        host_mole_fraction_sum = 0;
        inclusion_mole_fraction_sum = 0;
            
            % Check if  component sum is 1
            for i = 1:length(host_mole_fraction)
                host_mole_fraction_sum = host_mole_fraction_sum + host_mole_fraction{i};
            end
            
                if abs(host_mole_fraction_sum - 1) > 1e-14
                fprintf('Host components do not sum to 1! Cannot proceed with calculation. Try again\n');
                    return
                end
            
            for i = 1:length(inclusion_mole_fraction)
                inclusion_mole_fraction_sum = inclusion_mole_fraction_sum + inclusion_mole_fraction{i};
            end
            
                if abs(inclusion_mole_fraction_sum - 1) > 1e-14
                fprintf('Inclusion components do not sum to 1! Cannot proceed with calculation. Try again\n');
                    return
                end
            
            % Check if  shear modulus or poisson ratios are avaialble in the dataset
            for i = 1:1:length(host_abv)
                shear_modulus_check = isnan(app.hp_dataset{host_abv{i},'shear_modulus'});
                poisson_ratio_check = isnan(app.hp_dataset{host_abv{i},'poisson_ratio'});
                
                if shear_modulus_check == 1
                    
                        if poisson_ratio_check == 1
                        fprintf('No shear modulus or poisson ratio for the host phase %s! Cannot proceed with calculation. Manually input your own in the thermodynamic database if needed.\n', host_abv{i});
                        return
                        
                        end
                        
                end
                
            end
            
        % Setting Graph Parameters from User Inputs on the GUI
        HiT = app.HighTemperatureEditField.Value;
        LowT = app.LowTemperatureEditField.Value;
        Tint = app.TemperatureEditField_int.Value;
            
        LowP = app.LowPressureEditField.Value/100;
        HighP = app.HighPressureEditField.Value/100;
        Pint = app.PressureEditField_int.Value;
            
        HiPincl = app.PinclHigh.Value/100;
        LowPincl = app.PinclLow.Value/100;
        Pinc_int = app.Pincl_int.Value/100;
        P_incl = LowPincl:Pinc_int:HiPincl;
        Pincl_count = 1;
            
        T = LowT:Tint:HiT;
        record = zeros(length(T),length(P_incl));
        
        status_update = waitbar(LowP,'Inclusion Pressure (MPa)','Name','Calculating isomekes...',...
                        'CreateCancelBtn','setappdata(gcbf,''canceling'',1)');

        setappdata(status_update,'canceling',0);               
                    
            % To run the iterative calculations to establish the Isomeke Graph
            for P_incl = LowPincl:Pinc_int:HiPincl
                
                    if getappdata(status_update,'canceling')
                        break
                    end
                
                Pincl_count = Pincl_count + 1; 
                 
                T_entrapment = LowT + 273.15;
       
                for T_count = 1:1:length(T)
                
                    if T_count > 1
                   
                        T_entrapment = Tint + T_entrapment;
                
                    end
            
                options = optimoptions(@fsolve, 'display','off', 'tolfun', 1e-10); 
                Obj_func = @(P_entrapment) elastic_model(P_incl,P_entrapment,T_entrapment,inclusion_abv,host_abv,host_mole_fraction,inclusion_mole_fraction,app.hp_dataset);
                P_entrapment = fsolve(Obj_func, 0.001, options);
                
                record(T_count, Pincl_count - 1) = P_entrapment*100;
                       
                app.ActiveCalculationStepEditField.Value = P_entrapment*100;
                app.TupdateField.Value = T_entrapment-273.15;
                app.PinclupdateField.Value = P_incl*100;
                
                waitbar(T_count/length(T),status_update,sprintf('%12.0f',P_incl*100))
            
                end
                                                                            
            end 
            
            delete(status_update)
             
            for i = 1:1:length(host_abv)
                shear_modulus_check = isnan(app.hp_dataset{host_abv{i},'shear_modulus'});
                poisson_ratio_check = isnan(app.hp_dataset{host_abv{i},'poisson_ratio'});
      
                    if shear_modulus_check == 1   
                        fprintf('WARNING: No experimental shear modulus for the host phase %s! Manually input your own in the thermodynamic database if needed.\n', host_abv{i});
                        
                        if poisson_ratio_check == 0
                        fprintf('Using poisson ratio to calculate shear modulus for %s.\n', host_abv{i}); 
                         
                        end
                        
                    end
                    
            end

            disp('Entrapment pressure calculation complete.')
                                
        % To Generate the Isomeke Graph                
        plot(app.UIAxes, T, record, 'k', 'linewidth',1)
        ylabel(app.UIAxes, 'Pressure (MPa)','FontSize',12,'FontWeight','bold')
        xlabel(app.UIAxes, 'Temperature ({\circ}C)','FontSize',12,'FontWeight','bold')              
        axis(app.UIAxes, [LowT HiT LowP*100 HighP*100])
                         
        % Saves output to Matlab workspace so the data is not lost       
        assignin('base', 'isomekes_record', record); 
        assignin('base', 'T', T); 
        assignin('base', 'LowT', LowT);
        assignin('base', 'HiT', HiT);
        assignin('base', 'LowP', LowP);
        assignin('base', 'HighP', HighP);
           
        % Saves output to isomekes_record.xlsx in the folder with the files  
        filename = 'isomekes_record.xlsx';
        new_array = [T' record];
        pincl_string = string(LowPincl*100:Pinc_int*100:HiPincl*100);
        pincl_string = strrep(pincl_string,'-', '_');
        pincl_string_concat = strcat('Pincl',pincl_string);
        prange = cellstr(pincl_string_concat);
        new_table = array2table(new_array);
        variables = ['Temperature', prange];
        new_table.Properties.VariableNames = variables;
        writetable(new_table,filename);
             
        end

        % Button pushed function: LoadDataButton
        function LoadData(app, event)
            
            % Loads data from Isomeke Graph into spreadsheet to inspect values
            
                isomekes = readtable('isomekes_record.xlsx','ReadVariableNames',true);
                app.UITable.Data = isomekes;
               
        end

        % Button pushed function: EntrapmentPressureButton
        function P_Calc(app, event)
    clc         
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Code here performs an individual Pressure Calculation
          
        % Step 1: Read Excel Spreadsheet for all Thermodynamic Properties
        hp_dataset = readtable('thermodynamic_properties.xlsx','ReadRowNames',true);          
          
        % Step 2: Load user Inputs of Host and Inclusion Composition. Can range from 1 to 5 components to best represent mineral.   
        inclusion_abv{1} = app.Component1DropDown.Value;
        in_mol1 = app.Component1mol.Value;
        inclusion_mole_fraction{1} = in_mol1;
    
        inclusion_abv{2} = app.Component2DropDown.Value;
        in_mol2 = app.Component2mol.Value;
        inclusion_mole_fraction{2} = in_mol2;
            
        inclusion_abv{3} = app.Component3DropDown.Value;
        in_mol3 = app.Component3mol.Value;
        inclusion_mole_fraction{3} = in_mol3;
    
        inclusion_abv{4} = app.Component4DropDown.Value;
        in_mol4 = app.Component4mol.Value;
        inclusion_mole_fraction{4} = in_mol4;
            
        inclusion_abv{5} = app.Component5DropDown.Value;
        in_mol5 = app.Component5mol.Value;
        inclusion_mole_fraction{5} = in_mol5;
          
        host_abv{1} = app.HostDropDown.Value;
        h_mol1 = app.HostMol1.Value;
        host_mole_fraction{1} = h_mol1;
                    
        host_abv{2} = app.HostDropDown_2.Value;
        h_mol2 = app.HostMol2.Value;
        host_mole_fraction{2} = h_mol2;
                    
        host_abv{3} = app.HostDropDown_3.Value;
        h_mol3 = app.HostMol3.Value;
        host_mole_fraction{3} = h_mol3;
            
        host_abv{4} = app.HostDropDown_4.Value;
        h_mol4 = app.HostMol4.Value;
        host_mole_fraction{4} = h_mol4;
            
        host_abv{5} = app.HostDropDown_5.Value;
        h_mol5 = app.HostMol5.Value;
        host_mole_fraction{5} = h_mol5;
      
        % Get rid of hosts and inclusions for which no component mole fraction has been entered
        count_incl = 1;
        count_host = 1;
            
            for ii = 1:5
                if host_mole_fraction{ii} ~= 0
                    keep_host_abv{count_host} = host_abv{ii};
                    keep_host_mole_fraction{count_host} = host_mole_fraction{ii};
                    count_host = count_host + 1;
                end
                if inclusion_mole_fraction{ii} ~= 0
                    keep_inclusion_abv{count_incl} = inclusion_abv{ii};
                    keep_inclusion_mole_fraction{count_incl} = inclusion_mole_fraction{ii};
                    count_incl = count_incl + 1;
                end
            end
            
        host_abv = keep_host_abv;
        host_mole_fraction = keep_host_mole_fraction;
        inclusion_abv = keep_inclusion_abv;
        inclusion_mole_fraction = keep_inclusion_mole_fraction;
        host_mole_fraction_sum = 0;
        inclusion_mole_fraction_sum = 0;
            
            % Check if  component sum is 1
            for i = 1:length(host_mole_fraction)
                host_mole_fraction_sum = host_mole_fraction_sum + host_mole_fraction{i};
            end
               
                if abs(host_mole_fraction_sum - 1) > 1e-14
                fprintf('Host components do not sum to 1! Cannot proceed with calculation. Try again\n');
                    return
                end
            
            for i = 1:length(inclusion_mole_fraction)
                inclusion_mole_fraction_sum = inclusion_mole_fraction_sum + inclusion_mole_fraction{i};
            end
            
                if abs(inclusion_mole_fraction_sum - 1) > 1e-14
                fprintf('Inclusion components do not sum to 1! Cannot proceed with calculation. Try again\n');
                    return
                end
            
            % Check if  shear modulus or poisson ratios are avaialble in the dataset
            for i = 1:1:length(host_abv)
                shear_modulus_check = isnan(app.hp_dataset{host_abv{i},'shear_modulus'});
                poisson_ratio_check = isnan(app.hp_dataset{host_abv{i},'poisson_ratio'});
                
                if shear_modulus_check == 1
                    
                        if poisson_ratio_check == 1
                        fprintf('No shear modulus or poisson ratio for the host phase %s! Cannot proceed with calculation. Manually input your own in the thermodynamic database if needed.\n', host_abv{i});
                        return
                        
                        end
                        
                end
                
            end
            
        % Step 3: Load user inputs of Inclusion Pressure from Raman (or other source) and independent Temperature estimate  
        T_entrapment_celsius = app.TemperatureEditField.Value;      
        T_entrapment = T_entrapment_celsius + 273.15;
        P_incl = app.PinclusionEditField.Value/100;
            
            
        % Step 4: Perform the Calculation for Entrapment Pressure  
        options = optimoptions(@fsolve, 'display','off', 'tolfun', 1e-10); 
        Obj_func = @(P_entrapment) elastic_model(P_incl,P_entrapment,T_entrapment,inclusion_abv,host_abv,host_mole_fraction,inclusion_mole_fraction,app.hp_dataset);
        P_entrapment = fsolve(Obj_func, 0.001, options); % 0.001 = start pressure value in kbars
        app.PentrapEditField.Value = P_entrapment*100;
            
            for i = 1:1:length(host_abv)
                shear_modulus_check = isnan(app.hp_dataset{host_abv{i},'shear_modulus'});
                poisson_ratio_check = isnan(app.hp_dataset{host_abv{i},'poisson_ratio'});
      
                    if shear_modulus_check == 1   
                        fprintf('WARNING: No experimental shear modulus for the host phase %s! Manually input your own in the thermodynamic database if needed.\n', host_abv{i});
                        
                        if poisson_ratio_check == 0
                        fprintf('Using poisson ratio to calculate shear modulus for %s.\n', host_abv{i}); 
                         
                        end
                        
                    end
                    
            end

            disp('Entrapment pressure calculation complete.')
          
          
        end

        % Button pushed function: EntrapmentTempButton
        function T_Calc(app, event)
    clc
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        % Code here performs an individual Temperature Calculation  
            
        % Step 1: Read Excel Spreadsheet for all Thermodynamic Properties
        hp_dataset = readtable('thermodynamic_properties.xlsx','ReadRowNames',true);          
          
        % Step 2: Load user Inputs of Host and Inclusion Composition. Can range from 1 to 5 components to best represent mineral.   
        inclusion_abv{1} = app.Component1DropDown.Value;
        in_mol1 = app.Component1mol.Value;
        inclusion_mole_fraction{1} = in_mol1;
    
        inclusion_abv{2} = app.Component2DropDown.Value;
        in_mol2 = app.Component2mol.Value;
        inclusion_mole_fraction{2} = in_mol2;
            
        inclusion_abv{3} = app.Component3DropDown.Value;
        in_mol3 = app.Component3mol.Value;
        inclusion_mole_fraction{3} = in_mol3;
    
        inclusion_abv{4} = app.Component4DropDown.Value;
        in_mol4 = app.Component4mol.Value;
        inclusion_mole_fraction{4} = in_mol4;
            
        inclusion_abv{5} = app.Component5DropDown.Value;
        in_mol5 = app.Component5mol.Value;
        inclusion_mole_fraction{5} = in_mol5;
          
        host_abv{1} = app.HostDropDown.Value;
        h_mol1 = app.HostMol1.Value;
        host_mole_fraction{1} = h_mol1;
                    
        host_abv{2} = app.HostDropDown_2.Value;
        h_mol2 = app.HostMol2.Value;
        host_mole_fraction{2} = h_mol2;
                    
        host_abv{3} = app.HostDropDown_3.Value;
        h_mol3 = app.HostMol3.Value;
        host_mole_fraction{3} = h_mol3;
            
        host_abv{4} = app.HostDropDown_4.Value;
        h_mol4 = app.HostMol4.Value;
        host_mole_fraction{4} = h_mol4;
            
        host_abv{5} = app.HostDropDown_5.Value;
        h_mol5 = app.HostMol5.Value;
        host_mole_fraction{5} = h_mol5;
            
        % Get rid of hosts and inclusions for which no component mole fraction has been entered
        count_incl = 1;
        count_host = 1;
            
            for ii = 1:5
                if host_mole_fraction{ii} ~= 0
                    keep_host_abv{count_host} = host_abv{ii};
                    keep_host_mole_fraction{count_host} = host_mole_fraction{ii};
                    count_host = count_host + 1;
                end
                if inclusion_mole_fraction{ii} ~= 0
                    keep_inclusion_abv{count_incl} = inclusion_abv{ii};
                    keep_inclusion_mole_fraction{count_incl} = inclusion_mole_fraction{ii};
                    count_incl = count_incl + 1;
                end
            end
         
            
        host_abv = keep_host_abv;
        host_mole_fraction = keep_host_mole_fraction;
        inclusion_abv = keep_inclusion_abv;
        inclusion_mole_fraction = keep_inclusion_mole_fraction;
        host_mole_fraction_sum = 0;
        inclusion_mole_fraction_sum = 0;
            
            % Check if  component sum is 1
            for i = 1:length(host_mole_fraction)
                host_mole_fraction_sum = host_mole_fraction_sum + host_mole_fraction{i};
            end
            
                if abs(host_mole_fraction_sum - 1) > 1e-14
                fprintf('Host components do not sum to 1! Cannot proceed with calculation. Try again\n');
                    return
                end
            
            for i = 1:length(inclusion_mole_fraction)
                inclusion_mole_fraction_sum = inclusion_mole_fraction_sum + inclusion_mole_fraction{i};
            end
            
                if abs(inclusion_mole_fraction_sum - 1) > 1e-14
                fprintf('Inclusion components do not sum to 1! Cannot proceed with calculation. Try again\n');
                    return
                end
           
            % Check if  shear modulus or poisson ratios are avaialble in the dataset
            for i = 1:1:length(host_abv)
                shear_modulus_check = isnan(app.hp_dataset{host_abv{i},'shear_modulus'});
                poisson_ratio_check = isnan(app.hp_dataset{host_abv{i},'poisson_ratio'});
                
                if shear_modulus_check == 1
                    
                        if poisson_ratio_check == 1
                        fprintf('No shear modulus or poisson ratio for the host phase %s! Cannot proceed with calculation. Manually input your own in the thermodynamic database if needed.\n', host_abv{i});
                        return
                        
                        end
                        
                end
                
            end
                         
        % Step 3: Load user inputs of Inclusion Pressure from Raman (or other source) and independent Temperature estimate  
        P_entrapment = app.PressureEditField.Value/100;      
        P_incl = app.PinclusionEditField.Value/100;
            
        % Step 4: Perform the Calculation for Entrapment Temperature  
        options = optimoptions(@fsolve, 'display','off', 'tolfun', 1e-10); 
        Obj_func = @(T_entrapment) elastic_model(P_incl,P_entrapment,T_entrapment,inclusion_abv,host_abv,host_mole_fraction,inclusion_mole_fraction,app.hp_dataset);
        T_entrapment_kelvin = fsolve(Obj_func, 273, options);
        T_entrapment = T_entrapment_kelvin - 273.15;
        app.TentrapEditField.Value = T_entrapment;
            
            for i = 1:1:length(host_abv)
                shear_modulus_check = isnan(app.hp_dataset{host_abv{i},'shear_modulus'});
                poisson_ratio_check = isnan(app.hp_dataset{host_abv{i},'poisson_ratio'});
      
                    if shear_modulus_check == 1   
                        fprintf('WARNING: No experimental shear modulus for the host phase %s! Manually input your own in the thermodynamic database if needed.\n', host_abv{i});
                        
                        if poisson_ratio_check == 0
                        fprintf('Using poisson ratio to calculate shear modulus for %s.\n', host_abv{i}); 
                        
                        end
                        
                    end
                    
            end

            disp('Entrapment temperature calculation complete.')
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CalculatorTab = uitab(app.TabGroup);
            app.CalculatorTab.Title = 'Calculator';
            app.TItleLabel = uilabel(app.CalculatorTab);
            app.TItleLabel.Text = 'SOLID INCLUSION CALCULATOR';
            app.SetCompBackground = uibutton(app.CalculatorTab, 'push');
            app.SetCompBackground.Enable = 'off';
            app.SetCompBackground.Text = '';
            app.SetPTBackground = uibutton(app.CalculatorTab, 'push');
            app.SetPTBackground.Enable = 'off';
            app.SetPTBackground.Text = '';
            app.IsobarBackground = uibutton(app.CalculatorTab, 'push');
            app.IsobarBackground.Enable = 'off';
            app.IsobarBackground.Text = '';
            app.SetCompDark = uibutton(app.CalculatorTab, 'push');
            app.SetCompDark.Enable = 'off';
            app.SetCompDark.Text = '';
            app.SetPTDark = uibutton(app.CalculatorTab, 'push');
            app.SetPTDark.Enable = 'off';
            app.SetPTDark.Text = '';
            app.TextSetComp = uilabel(app.CalculatorTab);
            app.TextSetComp.Text = 'SET COMPOSITIONS';
            app.TextSetPT = uilabel(app.CalculatorTab);
            app.TextSetPT.Text = 'SET P-T VARIABLES';
            app.IsobarDark = uibutton(app.CalculatorTab, 'push');
            app.IsobarDark.Enable = 'off';
            app.IsobarDark.Text = '';
            app.IsomekeGraphButton = uibutton(app.CalculatorTab, 'push');
            app.IsomekeGraphButton.ButtonPushedFcn = createCallbackFcn(app, @IsobarGraph, true);
            app.IsomekeGraphButton.Text = 'Isomeke Graph';
            app.AnyTex2 = uilabel(app.CalculatorTab);
            app.AnyTex2.Text = 'Calculation takes a few minutes and outputs to "isomekes_record" ';
            app.AnyTex2_2 = uilabel(app.CalculatorTab);
            app.AnyTex2_2.Text = {'Set mole fraction of the Inclusion and Host phases. Use multiple end-member'; 'phases if necessary to account for solid solution.'};
            app.InclusionHeadint = uilabel(app.CalculatorTab);
            app.InclusionHeadint.Text = 'Inclusion';
            app.HostHeading = uilabel(app.CalculatorTab);
            app.HostHeading.Text = 'Host';
            app.Component2DropDown = uidropdown(app.CalculatorTab);
            app.Component2DropDown.Items = {'Component', 'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite', 'Option 93', 'Option 94', 'Option 95', 'Option 96'};
            app.Component2DropDown.Value = 'Component';
            app.Component3DropDown = uidropdown(app.CalculatorTab);
            app.Component3DropDown.Items = {'Component', 'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.Component3DropDown.Value = 'Component';
            app.Component3mol = uieditfield(app.CalculatorTab, 'numeric');
            app.Component3mol.Limits = [0 1];
            app.Component1DropDown = uidropdown(app.CalculatorTab);
            app.Component1DropDown.Items = {'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.Component1DropDown.Value = 'Albite';
            app.Component1mol = uieditfield(app.CalculatorTab, 'numeric');
            app.Component1mol.Limits = [0 1];
            app.Component1mol.Value = 1;
            app.Component2mol = uieditfield(app.CalculatorTab, 'numeric');
            app.Component2mol.Limits = [0 1];
            app.HostDropDown = uidropdown(app.CalculatorTab);
            app.HostDropDown.Items = {'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.HostDropDown.Value = 'Garnet_Almandine';
            app.HostDropDown_2 = uidropdown(app.CalculatorTab);
            app.HostDropDown_2.Items = {'Component', 'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.HostDropDown_2.Value = 'Component';
            app.HostDropDown_3 = uidropdown(app.CalculatorTab);
            app.HostDropDown_3.Items = {'Component', 'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.HostDropDown_3.Value = 'Component';
            app.HostMol3 = uieditfield(app.CalculatorTab, 'numeric');
            app.HostMol3.Limits = [0 1];
            app.HostMol2 = uieditfield(app.CalculatorTab, 'numeric');
            app.HostMol2.Limits = [0 1];
            app.HostMol1 = uieditfield(app.CalculatorTab, 'numeric');
            app.HostMol1.Limits = [0 1];
            app.HostMol1.Value = 1;
            app.HostDropDown_4 = uidropdown(app.CalculatorTab);
            app.HostDropDown_4.Items = {'Component', 'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.HostDropDown_4.Value = 'Component';
            app.HostMol4 = uieditfield(app.CalculatorTab, 'numeric');
            app.HostMol4.Limits = [0 1];
            app.HostDropDown_5 = uidropdown(app.CalculatorTab);
            app.HostDropDown_5.Items = {'Component', 'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.HostDropDown_5.Value = 'Component';
            app.HostMol5 = uieditfield(app.CalculatorTab, 'numeric');
            app.HostMol5.Limits = [0 1];
            app.Component4DropDown = uidropdown(app.CalculatorTab);
            app.Component4DropDown.Items = {'Component', 'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.Component4DropDown.Value = 'Component';
            app.Component4mol = uieditfield(app.CalculatorTab, 'numeric');
            app.Component4mol.Limits = [0 1];
            app.Component5DropDown = uidropdown(app.CalculatorTab);
            app.Component5DropDown.Items = {'Component', 'Actinolite_Fe', 'Albite', 'Analcite', 'Andalusite', 'Anhydrite', 'Annite', 'Anorthite', 'Anthophyllite', 'Antigorite', 'Apatite_Cl', 'Apatite_F', 'Apatite_OH', 'Aragonite', 'Brucite', 'Calcite', 'Chlorite', 'Chloritoid_Fe', 'Chloritoid_Mg', 'Chrysotile', 'Clinoenstatite', 'Clinopyroxene_Ca_eskola', 'Clinopyroxene_Ca_tschermak', 'Clinopyroxene_Mg_tschermak', 'Clinozoisite', 'Coesite', 'Cordierite', 'Cordierite_OH', 'Corundum', 'Cristobalite', 'Cummingtonite', 'Diamond', 'Diopside', 'Dolomite', 'Enstatite', 'Epidote', 'Epidote_Fe', 'Fayalite', 'Ferrosilite', 'Forsterite', 'Garnet_Almandine', 'Garnet_Andradite', 'Garnet_Grossular', 'Garnet_Pyrope', 'Garnet_Spessartine', 'Gehlenite', 'Glaucophane', 'Glaucophane_Fe', 'Goethite', 'Graphite', 'Grunerite', 'Halite', 'Hedenbergite', 'Hematite', 'Ilmenite', 'Jadeite', 'Kalsilite', 'Kaolinite', 'Kyanite', 'Lawsonite', 'Leucite', 'Lizardite', 'Magnesite', 'Magnetite', 'Majorite', 'Microcline', 'Monticellite', 'Muscovite', 'Nepheline', 'Paragonite', 'Pargasite', 'Periclase', 'Phlogopite', 'Phlogopite_Na', 'Prehnite', 'Pumpellyite_FeAl', 'Pumpellyite_MgAl', 'Pyrite', 'Pyrrhotite', 'Quartz', 'Rhodochrosite', 'Rhodonite', 'Riebeckite', 'Rutile', 'Sanidine', 'Sapphirine', 'Siderite', 'Sillimanite', 'Sodalite', 'Sphene', 'Spinel', 'Staurolite_Fe', 'Staurolite_Mg', 'Stilbite', 'Stilpnomelane_Mg', 'Stishovite', 'Sulfur', 'Sylvite', 'Talc', 'Talc_Fe', 'Titanite_CaSi', 'Topaz', 'Tremolite', 'Tridymite', 'Ulvospinel', 'Vesuvianite', 'Wollastonite', 'Zircon', 'Zoisite'};
            app.Component5DropDown.Value = 'Component';
            app.Component5mol = uieditfield(app.CalculatorTab, 'numeric');
            app.Component5mol.Limits = [0 1];
            app.TemperatureEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.TemperatureEditField.Limits = [1 1500];
            app.TemperatureEditField.Value = 600;
            app.PentrapEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.PentrapEditField.ValueDisplayFormat = '%.1f';
            app.AnyText = uilabel(app.CalculatorTab);
            app.AnyText.Text = 'MPa';
            app.AnyTex2_3 = uilabel(app.CalculatorTab);
            app.AnyTex2_3.Text = 'Entrapment Temperature';
            app.AnyTex2_4 = uilabel(app.CalculatorTab);
            app.AnyTex2_4.Text = 'Calculated Inclusion Pressure';
            app.PinclusionEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.PinclusionEditField.Limits = [-10000 10000];
            app.PinclusionEditField.Value = 200;
            app.AnyText_2 = uilabel(app.CalculatorTab);
            app.AnyText_2.Text = 'MPa';
            app.anytext = uilabel(app.CalculatorTab);
            app.anytext.Text = '°C';
            app.EntrapmentPressureButton = uibutton(app.CalculatorTab, 'push');
            app.EntrapmentPressureButton.ButtonPushedFcn = createCallbackFcn(app, @P_Calc, true);
            app.EntrapmentPressureButton.Text = 'Entrapment Pressure';
            app.AnyTex2_5 = uilabel(app.CalculatorTab);
            app.AnyTex2_5.Text = {'Enter known P-T variables. Use buttons to calculate the unknown variable. '; ''};
            app.TentrapEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.TentrapEditField.ValueDisplayFormat = '%.1f';
            app.AnyText_3 = uilabel(app.CalculatorTab);
            app.AnyText_3.Text = '°C';
            app.EntrapmentTempButton = uibutton(app.CalculatorTab, 'push');
            app.EntrapmentTempButton.ButtonPushedFcn = createCallbackFcn(app, @T_Calc, true);
            app.EntrapmentTempButton.Text = 'Entrapment Temp.';
            app.ActiveCalculationStepEditFieldLabel = uilabel(app.CalculatorTab);
            app.ActiveCalculationStepEditFieldLabel.Text = '°C';
            app.ActiveCalculationStepEditFieldLabel_2 = uilabel(app.CalculatorTab);
            app.ActiveCalculationStepEditFieldLabel_2.Text = 'Active Calculation Step';
            app.ActiveCalculationStepEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.ActiveCalculationStepEditField.ValueDisplayFormat = '%11.3g';
            app.TupdateField = uieditfield(app.CalculatorTab, 'numeric');
            app.TupdateField.ValueDisplayFormat = '%11.3g';
            app.ActiveCalculationStepEditFieldLabel_3 = uilabel(app.CalculatorTab);
            app.ActiveCalculationStepEditFieldLabel_3.Text = '°C';
            app.AnyTex2_7 = uilabel(app.CalculatorTab);
            app.AnyTex2_7.Text = '*Lets you know program is running';
            app.SetPressureAxesLabel = uilabel(app.CalculatorTab);
            app.SetPressureAxesLabel.Text = 'Set Pressure Axes';
            app.SetTemperatureAxesLabel = uilabel(app.CalculatorTab);
            app.SetTemperatureAxesLabel.Text = 'Set Temperature Axes';
            app.toLabel = uilabel(app.CalculatorTab);
            app.toLabel.Text = 'to';
            app.ActiveCalculationStepEditFieldLabel_4 = uilabel(app.CalculatorTab);
            app.ActiveCalculationStepEditFieldLabel_4.Text = 'MPa';
            app.HighPressureEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.HighPressureEditField.Limits = [-10000 10000];
            app.HighPressureEditField.Value = 1000;
            app.LowPressureEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.LowPressureEditField.Limits = [-10000 10000];
            app.toLabel_2 = uilabel(app.CalculatorTab);
            app.toLabel_2.Text = 'to';
            app.HighTemperatureEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.HighTemperatureEditField.Limits = [0 2000];
            app.HighTemperatureEditField.Value = 1000;
            app.LowTemperatureEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.LowTemperatureEditField.Limits = [0 2000];
            app.LowTemperatureEditField.Value = 400;
            app.AnyTex2_8 = uilabel(app.CalculatorTab);
            app.AnyTex2_8.Text = 'MPa';
            app.RangeofInclPressureLabel = uilabel(app.CalculatorTab);
            app.RangeofInclPressureLabel.Text = 'Range of Incl. Pressure';
            app.ActiveCalculationStepEditFieldLabel_5 = uilabel(app.CalculatorTab);
            app.ActiveCalculationStepEditFieldLabel_5.Text = 'MPa';
            app.PinclHigh = uieditfield(app.CalculatorTab, 'numeric');
            app.PinclHigh.Limits = [-10000 10000];
            app.PinclHigh.Value = 300;
            app.PinclLow = uieditfield(app.CalculatorTab, 'numeric');
            app.PinclLow.Limits = [-10000 10000];
            app.PinclLow.Value = 100;
            app.toLabel_3 = uilabel(app.CalculatorTab);
            app.toLabel_3.Text = 'to';
            app.UIAxes = uiaxes(app.CalculatorTab);
            xlabel(app.UIAxes, 'Temperature (°C)')
            ylabel(app.UIAxes, 'Pressure (MPa)')
            app.PinclupdateField = uieditfield(app.CalculatorTab, 'numeric');
            app.PinclupdateField.ValueDisplayFormat = '%11.3g';
            app.ActiveCalculationStepEditFieldLabel_6 = uilabel(app.CalculatorTab);
            app.ActiveCalculationStepEditFieldLabel_6.Text = 'Pincl';
            app.PressureEditField = uieditfield(app.CalculatorTab, 'numeric');
            app.PressureEditField.Limits = [0.1 10000];
            app.PressureEditField.Value = 200;
            app.AnyTex2_6 = uilabel(app.CalculatorTab);
            app.AnyTex2_6.Text = 'Entrapment Pressure';
            app.anytext_2 = uilabel(app.CalculatorTab);
            app.anytext_2.Text = 'MPa';
            app.Pincl_int = uieditfield(app.CalculatorTab, 'numeric');
            app.Pincl_int.Limits = [-10000 10000];
            app.Pincl_int.Value = 100;
            app.PressureEditField_int = uieditfield(app.CalculatorTab, 'numeric');
            app.PressureEditField_int.Limits = [-10000 10000];
            app.PressureEditField_int.Value = 100;
            app.TemperatureEditField_int = uieditfield(app.CalculatorTab, 'numeric');
            app.TemperatureEditField_int.Limits = [0 2000];
            app.TemperatureEditField_int.Value = 100;
            app.IntervalsLabel = uilabel(app.CalculatorTab);
            app.IntervalsLabel.Text = 'Intervals';
            app.MinLabel = uilabel(app.CalculatorTab);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.CalculatorTab);
            app.MaxLabel.Text = 'Max';
            app.MolFractionLabel = uilabel(app.CalculatorTab);
            app.MolFractionLabel.Text = 'Mol Fraction';
            app.ActiveCalculationStepEditFieldLabel_7 = uilabel(app.CalculatorTab);
            app.ActiveCalculationStepEditFieldLabel_7.Text = 'Tent';
            app.ActiveCalculationStepEditFieldLabel_8 = uilabel(app.CalculatorTab);
            app.ActiveCalculationStepEditFieldLabel_8.Text = 'Pent';
            app.AnyTex2_20 = uilabel(app.CalculatorTab);
            app.AnyTex2_20.Text = 'MPa';
            app.OutputDataTab = uitab(app.TabGroup);
            app.OutputDataTab.Title = 'Output Data';
            app.LoadDataButton = uibutton(app.OutputDataTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadData, true);
            app.LoadDataButton.Text = 'Load Data';
            app.TItleLabel_2 = uilabel(app.OutputDataTab);
            app.TItleLabel_2.Text = 'SOLID INCLUSION CALCULATOR';
            app.UITable = uitable(app.OutputDataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 5'; 'Column 6'; 'Column 7'; 'Column 8'; 'Column 9'; 'Column 10'; 'Column 11'; 'Column 12'};
            app.UITable.RowName = {};
            app.AnyTex2_9 = uilabel(app.OutputDataTab);
            app.AnyTex2_9.Text = {'Loads data that was used to make most recent Isomeke Graph. The data was already saved as "isomekes_record.xlsx" in the root folder. '; ''};
            app.InstructionsandInformationTab = uitab(app.TabGroup);
            app.InstructionsandInformationTab.Title = 'Instructions and Information';
            app.TItleLabel_3 = uilabel(app.InstructionsandInformationTab);
            app.TItleLabel_3.Text = 'SOLID INCLUSION CALCULATOR';
            app.SetCompBackground_2 = uibutton(app.InstructionsandInformationTab, 'push');
            app.SetCompBackground_2.Enable = 'off';
            app.SetCompBackground_2.Text = '';
            app.SetCompDark_2 = uibutton(app.InstructionsandInformationTab, 'push');
            app.SetCompDark_2.Enable = 'off';
            app.SetCompDark_2.Text = '';
            app.TextSetComp_2 = uilabel(app.InstructionsandInformationTab);
            app.TextSetComp_2.Text = 'Instructions and Tips';
            app.SetCompBackground_3 = uibutton(app.InstructionsandInformationTab, 'push');
            app.SetCompBackground_3.Enable = 'off';
            app.SetCompBackground_3.Text = '';
            app.SetCompDark_3 = uibutton(app.InstructionsandInformationTab, 'push');
            app.SetCompDark_3.Enable = 'off';
            app.SetCompDark_3.Text = '';
            app.TextSetComp_3 = uilabel(app.InstructionsandInformationTab);
            app.TextSetComp_3.Text = 'Contact Information';
            app.AnyTex2_10 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_10.Text = 'Files Needed to Run Program';
            app.AnyTex2_11 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_11.Text = {'Miguel Cisneros (miguel.cisneros@erdw.ethz.ch)'; 'ETH Zurich'; 'Department of Earth Sciences'; 'Sonneggstrasse 5'; '8092 Zurich'; 'Switzerland'; ''; 'Kenneth Befus (Kenneth_Befus@Baylor.edu)'; 'Baylor University'; 'Department of Geosciences'; 'One Bear Place #97354'; 'Waco TX 76798'; ''; ''; ''; ''; ''; ''};
            app.AnyTex2_12 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_12.Text = {'+ Ensure all the required files are in the same folder:'; '       eos.m                                                              '; '       elastic_model.m                                                 '; '       elastic_model_P_foot.m                                                             '; '       solid_inclusion_calculator.mlapp                                                   '; '       thermodynamic_properties.xlsx                                                   '; ''; ' + Files are available for download at www.baylorvgp.com/, or the G3 data repository. '; '    '; ''};
            app.AnyTex2_13 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_13.Text = 'Set Compositions Instructions';
            app.AnyTex2_14 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_14.Text = {'+ Select components from the dropdowns to represent solid solution as closely as possible. Solid'; '   solution will affect thermobarometer results. '; ''; '+ Ensure Mol.% sums to a total of 1. This is user-dependent.'; ''; '+ If the required components are not available in the dropdown, check thermodynamic_properties.xlsx. '; '   Many more components are listed in that spreadsheet than what we have provided in the dropdown. '; '   Any component can be added. Similarly, thermodynamic properties can be updated with new'; '   experimental or theoretical constraints by updating thermodynamic_properties.xlsx. See the file'; '   thermodynamics_properties_references.xls for references of all variables used throughout calculations.'; '  '; ''};
            app.AnyTex2_15 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_15.Text = 'Set P-T Variables Instructions';
            app.AnyTex2_16 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_16.Text = {'+ Use this section to make thermobarometry calculations. '; ''; '+ Be sure inclusion-host assemblage is a good thermometer or barometer before running calculations.'; ''; '+ The model does not produce uncertainties or error bars. It uses exact values from the thermodynamic'; '    properties spreadsheet and the P, T, and Inclusion Pressure from user input. Each of those variables'; '    has inherent uncertainty. It is up to the user to iteratively run the script to establish uncertainties. '; '    '; ''};
            app.AnyTex2_17 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_17.Text = 'Isomeke Graph Instructions';
            app.AnyTex2_18 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_18.Text = {'+ The calculations will take a few minutes. The "active Step" portion lets you know if the software'; '    is running. Use reasonable ranges for Inclusion Pressure, Temperature, and Pressure for most efficiency. '; '    Open the file isomekes_record.xlsx to access the output isomeke calculations.'; '  '; ''; ''; ''};
            app.AnyTex2_19 = uilabel(app.InstructionsandInformationTab);
            app.AnyTex2_19.Text = 'Creators';
        end
    end

    methods (Access = public)

        % Construct app
        function app = solid_inclusion_calculator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end